classdef ciCameraWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        camInfo      matlab.ui.control.Table
        CameraLabel  matlab.ui.control.Label
        sceneInfo    matlab.ui.control.Table
        opticsInfo   matlab.ui.control.Table
        sensorInfo   matlab.ui.control.Table
        SceneLabel   matlab.ui.control.Label
        OpticsLabel  matlab.ui.control.Label
        SensorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ourCamera, ourScene)
            camProps = properties(ourCamera);
            ourCamData = cell2table(camProps);
            app.camInfo.Data = ourCamData;
            app.CameraLabel.Text = "Camera Type: " + class(ourCamera);
            
            ourOptics = ourCamera.cmodules(1).oi.optics;
            iePTable(ourOptics, 'uitable', app.opticsInfo,'format', 'embed');
            
            sceneProps = properties(ourScene);
            ourSceneData = cell2table(sceneProps);
            app.sceneInfo.Data = ourSceneData;
            app.SceneLabel.Text = "Scene: " + ourScene.sceneName;
            
            ourSensor = ourCamera.cmodules(1).sensor;
            iePTable(ourSensor, 'uitable', app.sensorInfo,'format', 'embed');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.camInfo = uitable(app.UIFigure);
            app.camInfo.ColumnName = {'Property'; 'Value'};
            app.camInfo.RowName = {};
            app.CameraLabel = uilabel(app.UIFigure);
            app.CameraLabel.Text = 'Camera:';
            app.sceneInfo = uitable(app.UIFigure);
            app.sceneInfo.ColumnName = {'Property'; 'Value'};
            app.sceneInfo.RowName = {};
            app.opticsInfo = uitable(app.UIFigure);
            app.opticsInfo.ColumnName = {'Property'; 'Value'};
            app.opticsInfo.RowName = {};
            app.sensorInfo = uitable(app.UIFigure);
            app.sensorInfo.ColumnName = {'Property'; 'Value'};
            app.sensorInfo.RowName = {};
            app.SceneLabel = uilabel(app.UIFigure);
            app.SceneLabel.Text = 'Scene:';
            app.OpticsLabel = uilabel(app.UIFigure);
            app.OpticsLabel.Text = 'Optics:';
            app.SensorLabel = uilabel(app.UIFigure);
            app.SensorLabel.Text = 'Sensor:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ciCameraWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end