classdef sceneWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        menuFileLoad                 matlab.ui.container.Menu
        menuFileSave                 matlab.ui.container.Menu
        menuSaveImage                matlab.ui.container.Menu
        menuFileRefresh              matlab.ui.container.Menu
        menuFileClose                matlab.ui.container.Menu
        menuEdit                     matlab.ui.container.Menu
        menuEditSceneName            matlab.ui.container.Menu
        menuEditNewScene             matlab.ui.container.Menu
        menuCopyScene                matlab.ui.container.Menu
        menuEditDelete               matlab.ui.container.Menu
        menuEditDeleteSome           matlab.ui.container.Menu
        menuSpectral                 matlab.ui.container.Menu
        menuSPDdiv                   matlab.ui.container.Menu
        menuSPDMult                  matlab.ui.container.Menu
        menuEditSetIlluminant        matlab.ui.container.Menu
        menuResampleWave             matlab.ui.container.Menu
        menuEditAdjustMonochrome     matlab.ui.container.Menu
        menuEditTransform            matlab.ui.container.Menu
        menuEditScaleSize            matlab.ui.container.Menu
        editCrop                     matlab.ui.container.Menu
        menuEditTranspose            matlab.ui.container.Menu
        menuEditRotate               matlab.ui.container.Menu
        menuEditRotCW                matlab.ui.container.Menu
        menuEditRotCCW               matlab.ui.container.Menu
        menuEditFlip                 matlab.ui.container.Menu
        menuEditFlipHorizontal       matlab.ui.container.Menu
        menuEditFlipVertical         matlab.ui.container.Menu
        DenoiseMenu                  matlab.ui.container.Menu
        menuEditFontSize             matlab.ui.container.Menu
        menuEditClearWindow          matlab.ui.container.Menu
        menuEditViewer               matlab.ui.container.Menu
        PlotMenu                     matlab.ui.container.Menu
        menuPlotRadiance             matlab.ui.container.Menu
        menuPlotRadianceE            matlab.ui.container.Menu
        menuPlotRadImGrid            matlab.ui.container.Menu
        menuPlotIllumPhotons         matlab.ui.container.Menu
        menuPlotIlluminant           matlab.ui.container.Menu
        menuPlotIlluminantImage      matlab.ui.container.Menu
        menuPlotReflectance          matlab.ui.container.Menu
        menuPlotImTrueSize           matlab.ui.container.Menu
        menuPlotMultipleRGB          matlab.ui.container.Menu
        SingleWindowMenu             matlab.ui.container.Menu
        MultipleWindowsMenu          matlab.ui.container.Menu
        menuPlotImageSpectral        matlab.ui.container.Menu
        menuPlotDepth                matlab.ui.container.Menu
        menuPlotDepthContour         matlab.ui.container.Menu
        menuScene                    matlab.ui.container.Menu
        menuSceneMacbeth             matlab.ui.container.Menu
        menuSceneMacbethTungsten     matlab.ui.container.Menu
        menuSceneMacbethD50          matlab.ui.container.Menu
        menuSceneMacbethD65          matlab.ui.container.Menu
        menuSceneMacbethC            matlab.ui.container.Menu
        menuSceneMacbethFluorescent  matlab.ui.container.Menu
        menuSceneMacbethVisIR        matlab.ui.container.Menu
        menuSceneLstar               matlab.ui.container.Menu
        menuScenesTest               matlab.ui.container.Menu
        menuScenesSweep              matlab.ui.container.Menu
        menuHarmonic                 matlab.ui.container.Menu
        menuSceneFreqOrient          matlab.ui.container.Menu
        menuSceneSlantedBar          matlab.ui.container.Menu
        menuSceneZonePlate           matlab.ui.container.Menu
        menuSceneMackay              matlab.ui.container.Menu
        menuSceneCheckerboard        matlab.ui.container.Menu
        menuScenePointArray          matlab.ui.container.Menu
        menuSceneGridLines           matlab.ui.container.Menu
        menuSceneTestLine            matlab.ui.container.Menu
        menuSceneRadialLines         matlab.ui.container.Menu
        menuUniform                  matlab.ui.container.Menu
        menuSceneUniformPhoton       matlab.ui.container.Menu
        menuSceneUniformEE           matlab.ui.container.Menu
        menuUniformD65               matlab.ui.container.Menu
        menuSceneUniformEESpecify    matlab.ui.container.Menu
        menuSceneUniformBBspecify    matlab.ui.container.Menu
        menuScenesRamp               matlab.ui.container.Menu
        menuSceneNoise               matlab.ui.container.Menu
        menuSceneFile                matlab.ui.container.Menu
        menuSceneMultiSpec           matlab.ui.container.Menu
        menuSceneChooseRGB           matlab.ui.container.Menu
        menuFileChooseFileMono       matlab.ui.container.Menu
        menuAn                       matlab.ui.container.Menu
        menuAnalyzeLine              matlab.ui.container.Menu
        menuAnalyzeLineH             matlab.ui.container.Menu
        menuAnalyzeLineV             matlab.ui.container.Menu
        menuAnalyzeLFFTH             matlab.ui.container.Menu
        menuAnalyzeLFFTv             matlab.ui.container.Menu
        menuAnalyzeLineWave          matlab.ui.container.Menu
        menuAnalyzeLWH               matlab.ui.container.Menu
        menuAnalyzeLWV               matlab.ui.container.Menu
        menuAnalyzeROI               matlab.ui.container.Menu
        menuLuminance                matlab.ui.container.Menu
        menuAnalyzeChromaticity      matlab.ui.container.Menu
        menPlotLumLin                matlab.ui.container.Menu
        PlotLuminance                matlab.ui.container.Menu
        menuAnIlluminantCCT          matlab.ui.container.Menu
        menuHelp                     matlab.ui.container.Menu
        menuHelpSceneProgrammers     matlab.ui.container.Menu
        menuHelpProgGuide            matlab.ui.container.Menu
        menuHelpAppNotes             matlab.ui.container.Menu
        UITable                      matlab.ui.control.Table
        Panel                        matlab.ui.container.Panel
        txtMessage                   matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        popupRender                  matlab.ui.control.DropDown
        text21                       matlab.ui.control.Label
        txtDisplayGamma              matlab.ui.control.Label
        editGamma                    matlab.ui.control.EditField
        btnPrev                      matlab.ui.control.Button
        btnNext                      matlab.ui.control.Button
        popupSelectScene             matlab.ui.control.DropDown
        sceneImage                   matlab.ui.control.UIAxes
        MoreinfoButton               matlab.ui.control.Button
        boxDescription               matlab.ui.container.ButtonGroup
        txtSceneDescription          matlab.ui.control.Label
        boxScene                     matlab.ui.container.ButtonGroup
        txtM                         matlab.ui.control.Label
        txtDist                      matlab.ui.control.Label
        editDistance                 matlab.ui.control.EditField
        txtDeg                       matlab.ui.control.Label
        txtFOV                       matlab.ui.control.Label
        editHorFOV                   matlab.ui.control.EditField
        txtCDM2                      matlab.ui.control.Label
        txtLum                       matlab.ui.control.Label
        editLuminance                matlab.ui.control.EditField
        imageMenu                    matlab.ui.container.ContextMenu
        HorLumLineMenu               matlab.ui.container.Menu
        RefreshMenu                  matlab.ui.container.Menu
    end

    
    methods (Access = private)
        function EditMenu_Callback(~)
            return
        end
        
        function btnInterpolate_Callback(app)
            % btnInterp - eliminate
            %
            % Read the data in the row and col edit fields.  Re-sample the current data
            % in the photons field so that it has the desired number of rows and
            % columns.
            %
            %
            [scene,val] = ieGetObject('SCENE');
            sz = sceneGet(scene,'size');
            r0 = sz(1); c0 = sz(2);
            
            % Call GUI to set new row and col dimensions.
            rc = sceneSetRowCol;
            figure(gcbf);  % Return control to this figure.
            newRow = rc(1); newCol = rc(2);
            % These are the desired values determined by sceneSetRowCol
            
            % Find the appropriate scale factor.
            sFactor = [newRow/r0, newCol/c0];
            scene = sceneInterpolate(scene,sFactor);
            ieReplaceObject(scene,val);
            app.refresh;
        end
        
        %----------- There are no row/col edit entries.
        % function editCol_CreateFcn(~)
        % 
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        
        % %%%%%%%%%%%
        % function editRow_Callback(app)
        %     [scene,val] = ieGetObject('SCENE');
        %     scene.nRows = str2double(get(hObject,'String'));
        %     ieReplaceObject(scene,val);
        % end
        % 
        % %-----------
        % function editRow_CreateFcn(~)
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        
        %-----------
        function menuFileRef_Callback(app)
            % Possible deprecated for the call below:  Refresh callback.
            app.refresh;
        end
        
        function menuPlotIlluminantComment_Callback(~)
            % Deprecated - Plot | Illuminant comment
            scene = ieGetObject('SCENE');
            disp(sceneGet(scene,'illuminantComment'));
        end
        
        function popupImScale_Callback(app)
            % Rescale the scene data spatially.  To be eliminated
            
            contents = get(hObject,'String');
            str = contents{get(hObject,'Value')};
            switch lower(str)
                case 'x 1'
                    return;
                case 'x 2'
                    sFactor = 2;
                case 'x 4'
                    sFactor = 4;
                case 'x 1/2'
                    sFactor = 1/2;
                case 'x 1/4'
                    sFactor = 1/4;
                otherwise
                    error('Unknown scale factor');
            end
            [scene,val] = ieGetObject('SCENE');
            scene = sceneInterpolate(scene,sFactor);
            ieReplaceObject(scene,val);
            app.refresh;
        end
        
        %{
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.CloseRequestFcn = 'sceneClose';
            app.figure1.KeyPressFcn = 'sceneKeyPress';
        end
        %
        %}
        
        %-----------
        function sceneClose(app)
            sceneW = ieSessionGet('scene window');
            if isequal(app,sceneW)
                % If this app is the one in the database, clear it.
                ieSessionSet('scene window',[]);
            end
            delete(app);
        end
    end
    
    methods (Access = public)
        
        %------
        function sceneAdd(app,scene)
            % app.sceneAdd(scene);
            ieAddObject(scene);
            app.refresh;
        end
        
        %------
        function refresh(app)
            % Fill scene window fields based on the current scene information
            %
            %    refresh(app)
            %
            % Fill the app fields with the current scene information
            %
            % Display of the RGB representation of the spectral radiance data is
            % handled separately by sceneShowImage.
            %
            % Copyright ImagEval Consultants, LLC, 2003.
            %
            % See also
            %   imageSPD, sceneWindow_App
            
            
            % We also display the currently selected scene.
            [scene, val] = ieGetObject('SCENE');
            
            if isempty(scene)
                ieInWindowMessage('No scene selected.  Inserting default.',app,2);
                scene = sceneCreate;
                ieAddObject(scene);
            end
            
            if ~isfield(scene,'depthMap')
                % Text boxes on right: we should reduce the fields in SCENE.
                app.editDistance.Visible = 'on';
                app.txtDist.Visible      = 'on';
                app.txtM.Visible         = 'on';
                app.editDistance.Value   = num2str(sceneGet(scene,'distance'));
            else
                % There is a depth map, so don't show the distance box.
                app.editDistance.Visible = 'off';
                app.txtDist.Visible      = 'off';
                app.txtM.Visible         = 'off';
            end
            
            % Adjust the scene popupSelectScene contents
            sceneNames = vcGetObjectNames('scene',true);
            
            % 'New Scene' is always first.  We are having a problem if there are
            % two scenes with the same name.
            Items = cellMerge({'New Scene'}, sceneNames);
            
            % The scenes plus 'New Scene' should be the items in the popup
            app.popupSelectScene.Items = Items;
            app.popupSelectScene.Value = Items{1 + val};
            
            meanL = sceneGet(scene,'mean luminance');
            app.editLuminance.Value= sprintf('%.1f',meanL);
            app.editHorFOV.Value = sprintf('%.2f',sceneGet(scene,'fov'));
            
            % Show the scene data
            app.sceneDisplayData(scene);
            
            % Should we be using 
            % or this?
            renderFlag = sceneGet(scene,'render flag index');
            % renderFlag = find(contains(app.popupRender.Items,app.popupRender.Value));

            % Get the integer that indicates which element of the displayFlag is
            % selected.
            % renderFlag = sceneGet(scene,'render flag index');
            
            % Get the display gamma value from the app UI
            gam = str2double(app.editGamma.Value);
            
            % Display the RGB image in the sceneAxis of the scene window
            sceneShowImage(scene,renderFlag,gam,app);
            
            % Make sure it is visible
            figure(app.figure1);
        end
        
        %------
        function sceneDisplayData(app, scene)
            %
            %  iePTable(sceneCreate);
            iePTable(scene,'uitable',app.UITable,'format','embed');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sceneWindow_OpeningFcn(app, varargin)
            
            % Permits calling as sceneWindow(scene);
            if ~isempty(varargin) ...
                    && isstruct(varargin{1}) ...
                    && strcmp(varargin{1}.type,'scene')
                scene = varargin{1};
                ieAddObject(scene);
            end
            
            % Do not render during startup
            app.figure1.Visible = false;

            % see if simplifying interactions makes thing faster
            if getpref('ISET', 'fastAxes', false)
                app.sceneImage.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                app.sceneImage.Toolbar = [];
            end

            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                s = get(0);
                wPos = ISETprefs.wPos;
                if numel(wPos{2}) >= 2
                    if wPos{2}(1) < 1 % we have old-style
                        app.figure1.Position(1:2) = round(wPos{2}(1:2) .* s.ScreenSize(3:4));
                    else
                        app.figure1.Position(1:2) = wPos{2}(1:2);
                    end
                end
            end
            % we can set row striping, but for now just use main background
            % color
            app.UITable.BackgroundColor = [app.figure1.Color;app.figure1.Color];
            
            % sceneOpen(app);
            dbApp = ieSessionGet('scene window');
            
            if isempty(dbApp) || ~isvalid(dbApp)
                % Store this app in the database
                ieSessionSet('scene window',app);
            end
            
            % Refresh the font size
            ieFontInit(app.figure1);
            
            app.figure1.Name = sprintf('ISET Scene %s',datetime('now','Format','HH:mm:ss'));            
            
            app.refresh;
            
            % show font slightly smaller than surrounding
            app.UITable.FontSize = getpref('ISET', 'fontSize', 12) - 3;
            app.UITable.FontName = 'Courier';
            
            app.figure1.Visible = true;
            
        end

        % Menu selected function: FileMenu
        function FileMenu_Callback(app, event)
            
        end

        % Menu selected function: PlotLuminance
        function PlotLuminance_Callback(app, event)
            
            [scene,val] = ieGetObject('SCENE');
            
            % Check that the luminance field has been calculated
            if ~checkfields(scene,'data','luminance')
                [lum, meanL] = sceneCalculateLuminance(scene);
                scene = sceneSet(scene,'luminance',lum);
                scene = sceneSet(scene,'meanLuminance',meanL);
                vcReplaceAndSelectObject(scene,val);
            end
            
            % Plots log10 or linear luminance
            % Should be replaced by scenePlot call
            scenePlot(scene,'luminance mesh log');
            
        end

        % Menu selected function: PlotMenu
        function PlotMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

        end

        % Button pushed function: btnNext
        function btnNext_Callback(app, event)
            % Push button with the + on the right of the selection popup
            
            s  = ieSessionGet('selected','scene');
            nS = ieSessionGet('nobjects','scene');
            s = min(s + 1,nS);
            s = max(s,1);
            
            vcSetSelectedObject('scene',s);
            app.refresh;
        end

        % Button pushed function: btnPrev
        function btnPrev_Callback(app, event)
            % Push button with the - on the left of the selection popup
            
            s  = ieSessionGet('selected','scene');
            nS = ieSessionGet('nobjects','scene');
            s = min(s - 1,nS);
            s = max(s,1);
            
            vcSetSelectedObject('scene',s);
            app.refresh;
        end

        % Menu selected function: editCrop
        function editCrop_Callback(app, event)
            % Edit | Crop
            
            [scene,val]  = ieGetObject('SCENE');
            [scene,rect] = sceneCrop(scene);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    % Get the illuminant photons out and put them in the main photon
                    % slot.
                    illuminantSPD = sceneGet(scene,'illuminant photons');
                    sceneI = sceneSet(scene,'photons',illuminantSPD);
                    
                    % Crop them
                    sceneI = sceneCrop(sceneI,rect);
                    illuminantSPD = sceneGet(sceneI,'photons');
                    
                    % Stuff them back into the illuminant slot
                    scene = sceneSet(scene,'illuminant photons',illuminantSPD);
                otherwise
            end
            
            ieReplaceObject(scene,val);
            app.refresh;
            
        end

        % Value changed function: editDistance
        function editDistance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Should be set(SCENE,'editDistance',value);
            [scene,val] = ieGetObject('SCENE');
            if ~isempty(scene)
                scene.distance = str2double(app.editDistance.Value);
                scene.consistency = 0;
                vcReplaceObject(scene,val);
            end
            app.refresh;

        end

        % Value changed function: editGamma
        function editGamma_Callback(app, event)
            app.refresh;
        end

        % Value changed function: editHorFOV
        function editHorFOV_Callback(app, event)
            % Edit Horizontal Field of view
            [scene,val] = ieGetObject('SCENE');
            if ~isempty(scene)
                scene = sceneSet(scene,'fov',str2double(app.editHorFOV.Value));
                ieReplaceObject(scene,val);
            end
            app.refresh;
        end

        % Value changed function: editLuminance
        function editLuminance_Callback(app, event)
            % Edit luminance box
            
            [scene, val] = ieGetObject('SCENE');
            if ~isempty(scene)
                meanL = str2double(app.editLuminance.Value); % str2double(get(hObject,'String'));
                scene = sceneAdjustLuminance(scene,meanL);
                ieReplaceObject(scene,val);
            end
            app.refresh;
        end

        % Menu selected function: menPlotLumLin
        function menPlotLumLin_Callback(app, event)
            % Plot | Lum | Mesh Lin
            [scene, val] = ieGetObject('SCENE');
            
            if ~checkfields(scene,'data','luminance')
                [scene.data.luminance, scene.data.meanL] = sceneCalculateLuminance(scene);
                ieReplaceObject(scene,val);
            end
            
            % Plots log10 or linear luminance as a mesh.
            % Should be replaced by scenePlot call
            scenePlot(scene,'luminance mesh linear');
        end

        % Menu selected function: menuAnIlluminantCCT
        function menuAnIlluminantCCT_Callback(app, event)
            % Analyze | Illuminant CCT
            
            % Analyze | Illuminant CCT
            scene = ieGetObject('scene');
            wave  = sceneGet(scene,'wave');
            spd   = sceneGet(scene,'illuminant energy');
            
            % This size makes the title visible
            str = sprintf('       ---------  Correlated color temp %.0f  ---------',spd2cct(wave,spd));
            msgbox(str,'Illuminant');
        end

        % Menu selected function: menuAn
        function menuAn_Callback(app, event)
            
        end

        % Menu selected function: menuAnalyzeChromaticity
        function menuAnalyzeChromaticity_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'chromaticity roi');
        end

        % Menu selected function: menuAnalyzeLFFTH
        function menuAnalyzeLFFTH_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance fft hline');
        end

        % Menu selected function: menuAnalyzeLFFTv
        function menuAnalyzeLFFTv_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance fft vline');
        end

        % Menu selected function: menuAnalyzeLWH
        function menuAnalyzeLWH_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'radiance hline');
        end

        % Menu selected function: menuAnalyzeLWV
        function menuAnalyzeLWV_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'radiance vline');
        end

        % Menu selected function: HorLumLineMenu, menuAnalyzeLineH
        function menuAnalyzeLineH_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance hline');
        end

        % Menu selected function: menuAnalyzeLineV
        function menuAnalyzeLineV_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance vline');

        end

        % Menu selected function: menuAnalyzeLineWave
        function menuAnalyzeLineWave_Callback(app, event)
            % Analyze LinWave
        end

        % Menu selected function: menuAnalyzeLine
        function menuAnalyzeLine_Callback(app, event)
            % Analyze Line
        end

        % Menu selected function: menuAnalyzeROI
        function menuAnalyzeROI_Callback(app, event)
            % Analyze | ROI
        end

        % Menu selected function: menuCopyScene
        function menuCopyScene_Callback(app, event)
            scene = ieGetObject('scene');
            newName = ieReadString('New scene name','new-scene');
            if isempty(newName),  return;
            else,                 scene = sceneSet(scene,'name',newName);
            end
            ieAddObject(scene);
            
            app.refresh;
            
        end

        % Menu selected function: menuEditAdjustMonochrome
        function menuEditAdjustMonochrome_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Adjust SPD | Adjust Monochrome Wavelength
            
            s = ieGetObject('scene');
            w = sceneGet(s,'wavelength');
            newWave = ieReadNumber('Enter new wavelength',w,'%.0f');
            s = sceneSet(s,'wave',newWave);
            
            vcReplaceAndSelectObject(s);
            app.refresh;
            
        end

        % Menu selected function: menuEditClearWindow
        function menuEditClearWindow_Callback(app, event)
            app.txtMessage.Text = '';
        end

        % Menu selected function: menuEditDeleteSome
        function menuEditDeleteSome_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Delete Some Scenes
            vcDeleteSomeObjects('scene');
            app.refresh;
        end

        % Menu selected function: menuEditDelete
        function menuEditDelete_Callback(app, event)
            % Edit | Delete Current Scene
            vcDeleteSelectedObject('SCENE');
            app.refresh;
        end

        % Menu selected function: menuEditFlipHorizontal
        function menuEditFlipHorizontal_Callback(app, event)
            % Edit | Transform | Flip | Horizontal
            [scene,val] = ieGetObject('SCENE');
            photons     = imageFlip(sceneGet(scene,'photons'),'leftRight');
            scene       = sceneSet(scene,'photons',photons);
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageFlip(sceneGet(scene,'illuminant photons'),'leftRight');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end            
            ieReplaceObject(scene,val);
            app.refresh;
        end

        % Menu selected function: menuEditFlipVertical
        function menuEditFlipVertical_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Flip | Vertical
            [scene,val] = ieGetObject('SCENE');
            photons     = imageFlip(sceneGet(scene,'photons'),'upDown');
            scene       = sceneSet(scene,'photons',photons);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageFlip(sceneGet(scene,'illuminant photons'),'upDown');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            ieReplaceObject(scene,val);
            app.refresh;
        end

        % Menu selected function: menuEditFlip
        function menuEditFlip_Callback(app, event)
            % Edit | Transform | Flip
            
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Edit | Change Font Size
            ieFontSizeSet(app.figure1);
        end

        % Menu selected function: menuEditNewScene
        function menuEditNewScene_Callback(app, event)
            % Edit | New Scene
            scene = sceneCreate;
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuEditRotCCW
        function menuEditRotCCW_Callback(app, event)
            
            % Edit | Transform | Rotate | CounterClockWise
            [scene,val] = ieGetObject('SCENE');
            photons     = imageRotate(sceneGet(scene,'photons'),'ccw');
            scene       = sceneSet(scene,'photons',photons);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageRotate(sceneGet(scene,'illuminant photons'),'ccw');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            
            ieReplaceObject(scene,val);
            app.refresh;
            
        end

        % Menu selected function: menuEditRotCW
        function menuEditRotCW_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Rotate | ClockWise
            [scene,val] = ieGetObject('SCENE');
            photons     = imageRotate(sceneGet(scene,'photons'),'cw');
            scene       = sceneSet(scene,'photons',photons);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageRotate(sceneGet(scene,'illuminant photons'),'cw');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            ieReplaceObject(scene,val);
            app.refresh;
            
        end

        % Menu selected function: menuEditRotate
        function menuEditRotate_Callback(app, event)
            % Edit | Transform | Rotate
        end

        % Menu selected function: menuEditScaleSize
        function menuEditScaleSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuEditScaleSize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            scene = ieGetObject('scene');
            
            % Call GUI to set ne�w row and col dimensions.
            sFactor = ieReadNumber('Spatial scale',2,'%.0f');
            if isempty(sFactor), disp('User canceled'); return; end
            
            % rc = sceneSetRowCol;
            figure(gcbf);  % Return control to this figure.
            scene = sceneInterpolate(scene,sFactor);
            vcReplaceObject(scene);
            app.refresh;
            
            return
        end

        % Menu selected function: menuEditSceneName
        function menuEditSceneName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,scene] = vcGetSelectedObject('SCENE');
            
            newName = ieReadString('New scene name','new-scene');
            if isempty(newName),  return;
            else,    scene = sceneSet(scene,'name',newName);
            end
            
            vcReplaceAndSelectObject(scene,val)
            refresh(app, hObject,eventdata,handles);
        end

        % Menu selected function: menuEditSetIlluminant
        function menuEditSetIlluminant_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Adjust SPD | Change Illuminant
            
            scene = ieGetObject('scene');
            scene = sceneAdjustIlluminant(scene);
            
            % Replace and refresh
            vcReplaceObject(scene);
            app.refresh;
        end

        % Menu selected function: menuEditTransform
        function menuEditTransform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuEditTransform (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
        end

        % Menu selected function: menuEditTranspose
        function menuEditTranspose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Transpose
            [val,scene] = vcGetSelectedObject('SCENE');
            photons     = imageTranspose(sceneGet(scene,'photons'));
            scene       = sceneSet(scene,'photons',photons);
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageTranspose(sceneGet(scene,'illuminant photons'),'leftRight');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            vcReplaceObject(scene,val); sceneWindow();
        end

        % Menu selected function: menuEditViewer
        function menuEditViewer_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = vcGetSelectedObject('scene');
            img = sceneGet(scene,'photons');
            rgb = imageSPD(img,sceneGet(scene,'wavelength'));
            ieViewer(rgb);
            
        end

        % Callback function
        function menuEditZoom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            zoom
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
        end

        % Menu selected function: menuFileChooseFileMono
        function menuFileChooseFileMono_Callback(app, event)
            % Scene | Choose | Monochrome
            %
            scene = sceneFromFile([],'monochrome');
            if isempty(scene), return; end
            ieAddObject(scene);
            app.refresh;
        end

        % Callback function: figure1, menuFileClose
        function menuFileClose_Callback(app, event)
            % File menu close
            
            % Deletes the sceneWindow_App if it is the one in the vcSESSION
            % database.  Closes the window.
            sceneClose(app);
        end

        % Menu selected function: menuFileLoad
        function menuFileLoad_Callback(app, event)
            % File | Load Scene
            vcImportObject('SCENE');
            app.refresh;
        end

        % Menu selected function: RefreshMenu, menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            % Menu:  File | Refresh .
            app.refresh;
        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            [~,scene] = vcGetSelectedObject('SCENE');
            vcSaveObject(scene);
        end

        % Menu selected function: menuHarmonic
        function menuHarmonic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % val = vcNewObjectValue('SCENE');
            scene = sceneCreate('harmonic');
            ieAddObject(scene);
            app.refresh;
            
        end

        % Menu selected function: menuHelpAppNotes
        function menuHelpAppNotes_Callback(app, event)
            % Help | Documentation (web)
            ieManualViewer('imageval code');
        end

        % Menu selected function: menuHelpProgGuide
        function menuHelpProgGuide_Callback(app, event)
            % Help | ISET functions (online)
            ieManualViewer('iset functions');
        end

        % Menu selected function: menuHelpSceneProgrammers
        function menuHelpSceneProgrammers_Callback(app, event)
            
            % Help | Scene Programmers (online)
            ieManualViewer('scene functions');
            
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
        end

        % Menu selected function: menuLuminance
        function menuLuminance_Callback(app, event)
            % Analyze | ROI Summary | Luminance
            scene = ieGetObject('scene');
            scenePlot(scene,'luminance roi');
        end

        % Menu selected function: menuPlotDepthContour
        function menuPlotDepthContour_Callback(app, event)
            % Plot | Depth Contour
            
            scene = ieGetObject('scene');
            if isempty(oiGet(scene,'depth map'))
                ieInWindowMessage('No depth data.',app,3);
            else
                scenePlot(scene,'depth map contour');
            end
        end

        % Menu selected function: menuPlotDepth
        function menuPlotDepth_Callback(app, event)
            % Plot | Depth Map
            scene = ieGetObject('scene');
            
            if isempty(sceneGet(scene,'depth map'))
                ieInWindowMessage('No depth map data.',app,3);
            else
                scenePlot(scene,'depth map');
            end
            
        end

        % Menu selected function: menuPlotIllumPhotons
        function menuPlotIllumPhotons_Callback(app, event)
            % Plot | Illuminant (photons)
            scenePlot(ieGetObject('scene'),'illuminant photons roi');
        end

        % Menu selected function: menuPlotIlluminantImage
        function menuPlotIlluminantImage_Callback(app, event)
            %  Plot | Illuminant image
            scenePlot(ieGetObject('scene'),'illuminant image');
        end

        % Menu selected function: menuPlotIlluminant
        function menuPlotIlluminant_Callback(app, event)
            % Plot | Illuminant (energy)
            scenePlot(ieGetObject('scene'),'illuminant energy roi');
        end

        % Menu selected function: menuPlotImTrueSize
        function menuPlotImTrueSize_Callback(app, event)
            % Plot | Image (RGB)
            
            % Shows the image in the window in a separate window, with just the image
            % at true size. The spatial scale is 1 to 1 with the data (true size).
            % Get the necessary display data
            scene = ieGetObject('scene');
            displayFlag = find(contains(app.popupRender.Items,app.popupRender.Value));
            gam = str2double(app.editGamma.Value);
            
            % Call same routine as shows the image in the GUI, but put the image in a
            % new graph window.  The image displays at true size.  To change the size,
            % you can call truesize(gcf,[row,col])
            rgb = sceneShowImage(scene,-1*displayFlag,gam);
            ieNewGraphWin; imagesc(rgb); axis image; 
            truesize;
        end

        % Menu selected function: menuPlotMultipleRGB
        function menuPlotMultipleRGB_Callback(app, event)

        end

        % Callback function
        function menuPlotNewGraphWindow_Callback(app, event)
            % Plot | New Graph Window
            ieNewGraphWin;
        end

        % Menu selected function: menuPlotRadImGrid
        function menuPlotRadImGrid_Callback(app, event)
            % Plot | Radiance image (grid)
            scenePlot(ieGetObject('SCENE'), 'radianceimagewithgrid');
        end

        % Menu selected function: menuPlotRadianceE
        function menuPlotRadianceE_Callback(app, event)
            % Plot | Radiance (Energy)
            scenePlot(ieGetObject('scene'),'radiance energy roi');
        end

        % Menu selected function: menuPlotReflectance
        function menuPlotReflectance_Callback(app, event)
            % Plot | Reflectance
            scenePlot(ieGetObject('scene'),'reflectance');
        end

        % Callback function
        function menuPlotWavebandImage_Callback(app, event)
            
            % Plot | Waveband image
            scenePlot(ieGetObject('SCENE'), 'radiance waveband image');
        end

        % Menu selected function: menuResampleWave
        function menuResampleWave_Callback(app, event)
            % Edit | Adjust SPD | Resample
            [scene,val] = ieGetObject('SCENE');
            scene = sceneInterpolateW(scene,[]);
            ieReplaceObject(scene,val);
            refresh(app, hObject,eventdata,handles);
        end

        % Menu selected function: menuSPDMult
        function menuSPDMult_Callback(app, event)
            % Edit | Adjust SPD | Multiply
            [scene,val] = ieGetObject('SCENE');
            scene = sceneSPDScale(scene,[],'multiply');
            ieReplaceObject(scene,val);
            app.refresh;
        end

        % Menu selected function: menuSPDdiv
        function menuSPDdiv_Callback(app, event)
            [scene,val] = ieGetObject('SCENE');
            scene = sceneSPDScale(scene,[],'divide');
            ieReplaceObject(scene,val);
            app.refresh;
        end

        % Menu selected function: menuSaveImage
        function menuSaveImage_Callback(app, event)
            % File | Save (.png)
            scene = ieGetObject('SCENE');
            sceneSaveImage(scene,[]);
        end

        % Menu selected function: menuSceneCheckerboard
        function menuSceneCheckerboard_Callback(app, event)
            scene = sceneCreate('checkerBoard');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneChooseRGB
        function menuSceneChooseRGB_Callback(app, event)
            % Scene | Choose | RGB
            scene = sceneFromFile([],'rgb');
            if isempty(scene), return; end
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneFile
        function menuSceneFile_Callback(app, event)
            
        end

        % Menu selected function: menuSceneFreqOrient
        function menuSceneFreqOrient_Callback(app, event)
            scene = sceneCreate('freqorientpattern');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneGridLines
        function menuSceneGridLines_Callback(app, event)
            % Scene | Patterns | PointArray (D65)
            scene = sceneCreate('GridLines',[],[],'d65');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneLstar
        function menuSceneLstar_Callback(app, event)
            % Scene | Macbeth Charts | L* chart
            % Creates vertical bars with equal L* steps
            scene =  sceneCreate('lstar');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneMacbethC
        function menuSceneMacbethC_Callback(app, event)
            % val = vcNewObjectValue('SCENE');
            scene =  sceneCreate('macbethC');
            ieAddObject(scene);
            app.refresh;
            
        end

        % Menu selected function: menuSceneMacbethD50
        function menuSceneMacbethD50_Callback(app, event)
            % Scene | Macbeth | D 50
            scene =  sceneCreate('macbethD50');
            ieAddObject(scene);
            app.refresh;
            
        end

        % Menu selected function: menuSceneMacbethD65
        function menuSceneMacbethD65_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Macbeth | D65
            scene =  sceneCreate('macbethD65');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneMacbethFluorescent
        function menuSceneMacbethFluorescent_Callback(app, event)
            % Scene | Macbeth | Fluorescent
            scene =  sceneCreate('macbethFluorescent');
            ieAddObject(scene);
            app.refresh;
            return
        end

        % Menu selected function: menuSceneMacbethTungsten
        function menuSceneMacbethTungsten_Callback(app, event)
            % Scene | Macbeth | Tungsten
            scene =  sceneCreate('macbethTungsten');
            ieAddObject(scene);
            app.refresh;
            
        end

        % Menu selected function: menuSceneMacbethVisIR
        function menuSceneMacbethVisIR_Callback(app, event)
            % Scene | Macbeth Charts | Visible-InfraRed
            wave = ieReadNumber('Enter waves','380:4:1068','%.0f');
            scene =  sceneCreate('macbethEE_IR',[],wave);
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneMacbeth
        function menuSceneMacbeth_Callback(app, event)
            
        end

        % Menu selected function: menuSceneMackay
        function menuSceneMackay_Callback(app, event)
            scene = sceneCreate('mackay');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneMultiSpec
        function menuSceneMultiSpec_Callback(app, event)
            % Scene | Choose | Multispectral
            scene = sceneFromFile([],'multispectral');
            if isempty(scene), return; end
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneNoise
        function menuSceneNoise_Callback(app, event)
            scene = sceneCreate('noise');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuScenePointArray
        function menuScenePointArray_Callback(app, event)
            % Scene | Patterns | PointArray (D65)
            scene = sceneCreate('pointarray',[],[],'d65');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneRadialLines
        function menuSceneRadialLines_Callback(app, event)
            scene = sceneCreate('radialLines');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneSlantedBar
        function menuSceneSlantedBar_Callback(app, event)
            scene = sceneCreate('slantedBar');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneTestLine
        function menuSceneTestLine_Callback(app, event)
            % Scene | Patterns | Line (D65 vert)
            % Impulse with a D65 spectral curve
            
            scene = sceneCreate('impulse1dd65',256);
            ieAddObject(scene);
            refresh(app);
        end

        % Menu selected function: menuSceneUniformBBspecify
        function menuSceneUniformBBspecify_Callback(app, event)
            % Scene | Uniform | Blackbody (specify)
            % sz = 32;  % Spatial samples
            
            % Read the size
            prompt = {'Image size','Color Temp','Wave (nm)'};
            def = {'32','5000','400:700'};
            answer = inputdlg(prompt,'Uniform blackbody',1,def);
            if isempty(answer), disp('User canceled'); return;
            else
                sz = str2double(answer{1});
                cTemp = str2double(answer{2});
                wave = eval(answer{3});
            end
            
            scene = sceneCreate('uniformbb',sz,cTemp,wave);
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneUniformEESpecify
        function menuSceneUniformEESpecify_Callback(app, event)
            % Scene | Uniform | Equal Energy (specify)
            sz = 32;  % Spatial samples
            wavelength = ieReadNumber('Enter waves','380:4:1068','%.0f');
            if isempty(wavelength), disp('User canceled.'); return; end
            
            scene = sceneCreate('uniformeeSpecify',sz,wavelength);
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneUniformEE
        function menuSceneUniformEE_Callback(app, event)
            % Scene | Uniform | Equal Energy
            scene = sceneCreate('uniformee');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneUniformPhoton
        function menuSceneUniformPhoton_Callback(app, event)
            % Scene | Uniform | Equal Photon
            scene = sceneCreate('uniformequalphoton');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuSceneZonePlate
        function menuSceneZonePlate_Callback(app, event)
            scene = sceneCreate('zonePlate');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuScene
        function menuScene_Callback(app, event)
            
        end

        % Menu selected function: menuScenesRamp
        function menuScenesRamp_Callback(app, event)
            scene = sceneCreate('ramp');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuScenesSweep
        function menuScenesSweep_Callback(app, event)
            scene = sceneCreate('sweep');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuScenesTest
        function menuScenesTest_Callback(app, event)
            
        end

        % Menu selected function: menuSpectral
        function menuSpectral_Callback(app, event)
            
        end

        % Menu selected function: menuUniformD65
        function menuUniformD65_Callback(app, event)
            % Scene | Uniform | D65
            scene = sceneCreate('uniformD65');
            ieAddObject(scene);
            app.refresh;
        end

        % Menu selected function: menuUniform
        function menuUniform_Callback(app, event)
            
        end

        % Menu selected function: menuPlotRadiance
        function menuPlotRadiance_Callback(app, event)
            % Plot | Radiance (Quanta)
            scenePlot(ieGetObject('scene'),'radiance photons roi');
            
        end

        % Value changed function: popupRender
        function popupRender_Callback(app, event)
            % We read the state of this popup inside of sceneSetEditsAndButtons.  The
            % value it has (contents{get(hObject,'Value')}) determines the displayFlag
            % value.  1 means Standard RGB (the default) and we will be adding other
            % display modes for NIR/SWIR and so forth over time.
            app.refresh;
        end

        % Value changed function: popupSelectScene
        function popupSelectScene_Callback(app, event)
            % Pulldown, really, for selecting the scene from the current
            % scenes
            if strcmp(app.popupSelectScene.Value,'New Scene')
                % Create the default scene
                ieAddObject(sceneCreate);
            else
                % The scenes in Items are numbered for uniqueness of the
                % Value.
                val = find(strcmp(app.popupSelectScene.Items,app.popupSelectScene.Value));
                vcSetSelectedObject('SCENE',val-1);
            end
            app.refresh;
        end

        % Button pushed function: MoreinfoButton
        function MoreinfoButtonPushed(app, event)
            iePTable(ieGetObject('scene'));
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % indices = event.Indices;
            % we don't do anything with cells here, just want iePTable
            iePTable(ieGetObject('scene'));
        end

        % Menu selected function: menuPlotImageSpectral
        function plotImageSpectral(app, event)
            scene = ieGetObject('scene');
            plotSpectralImage(scene);
        end

        % Menu selected function: SingleWindowMenu
        function rgbSingleWindow(app, event)
            % Plot | Multiple image (RGB)
            % Multiple figures with images of the session scene data
            singleWindow = true;
            imageMultiview('scene',[],singleWindow,app);
        end

        % Menu selected function: MultipleWindowsMenu
        function rgbMultipleWindows(app, event)
            % Plot | Multiple image (RGB)
            % Multiple figures with images of the session scene data
            singleWindow = false;
            imageMultiview('scene',[],singleWindow,app);
        end

        % Menu selected function: DenoiseMenu
        function menuEditDenoise_Callback(app, event)
            scene = ieGetObject('scene');
            scene = piAIdenoise(scene);
            ieReplaceObject(scene);
            app.refresh;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'ISET-Scene';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.figure1.Tag = 'figure1';
            app.FileMenu = uimenu(app.figure1);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenu_Callback, true);
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'FileMenu';
            app.menuFileLoad = uimenu(app.FileMenu);
            app.menuFileLoad.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoad_Callback, true);
            app.menuFileLoad.Text = 'Load scene data (.mat)';
            app.menuFileLoad.Tag = 'menuFileLoad';
            app.menuFileSave = uimenu(app.FileMenu);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Text = 'Save scene data (.mat)';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuSaveImage = uimenu(app.FileMenu);
            app.menuSaveImage.MenuSelectedFcn = createCallbackFcn(app, @menuSaveImage_Callback, true);
            app.menuSaveImage.Separator = 'on';
            app.menuSaveImage.Text = 'Save display (png)';
            app.menuSaveImage.Tag = 'menuSaveImage';
            app.menuFileRefresh = uimenu(app.FileMenu);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Separator = 'on';
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileClose = uimenu(app.FileMenu);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditSceneName = uimenu(app.menuEdit);
            app.menuEditSceneName.MenuSelectedFcn = createCallbackFcn(app, @menuEditSceneName_Callback, true);
            app.menuEditSceneName.Text = '(Re-) name scene';
            app.menuEditSceneName.Tag = 'menuEditSceneName';
            app.menuEditNewScene = uimenu(app.menuEdit);
            app.menuEditNewScene.MenuSelectedFcn = createCallbackFcn(app, @menuEditNewScene_Callback, true);
            app.menuEditNewScene.Separator = 'on';
            app.menuEditNewScene.Text = 'New scene';
            app.menuEditNewScene.Tag = 'menuEditNewScene';
            app.menuCopyScene = uimenu(app.menuEdit);
            app.menuCopyScene.MenuSelectedFcn = createCallbackFcn(app, @menuCopyScene_Callback, true);
            app.menuCopyScene.Text = 'Copy scene';
            app.menuCopyScene.Tag = 'menuCopyScene';
            app.menuEditDelete = uimenu(app.menuEdit);
            app.menuEditDelete.MenuSelectedFcn = createCallbackFcn(app, @menuEditDelete_Callback, true);
            app.menuEditDelete.Text = 'Delete current scene';
            app.menuEditDelete.Tag = 'menuEditDelete';
            app.menuEditDeleteSome = uimenu(app.menuEdit);
            app.menuEditDeleteSome.MenuSelectedFcn = createCallbackFcn(app, @menuEditDeleteSome_Callback, true);
            app.menuEditDeleteSome.Text = 'Delete some scenes';
            app.menuEditDeleteSome.Tag = 'menuEditDeleteSome';
            app.menuSpectral = uimenu(app.menuEdit);
            app.menuSpectral.MenuSelectedFcn = createCallbackFcn(app, @menuSpectral_Callback, true);
            app.menuSpectral.Separator = 'on';
            app.menuSpectral.Text = 'Adjust SPD';
            app.menuSpectral.Tag = 'menuSpectral';
            app.menuSPDdiv = uimenu(app.menuSpectral);
            app.menuSPDdiv.MenuSelectedFcn = createCallbackFcn(app, @menuSPDdiv_Callback, true);
            app.menuSPDdiv.Text = 'Divide';
            app.menuSPDdiv.Tag = 'menuSPDdiv';
            app.menuSPDMult = uimenu(app.menuSpectral);
            app.menuSPDMult.MenuSelectedFcn = createCallbackFcn(app, @menuSPDMult_Callback, true);
            app.menuSPDMult.Text = 'Multiply';
            app.menuSPDMult.Tag = 'menuSPDMult';
            app.menuEditSetIlluminant = uimenu(app.menuSpectral);
            app.menuEditSetIlluminant.MenuSelectedFcn = createCallbackFcn(app, @menuEditSetIlluminant_Callback, true);
            app.menuEditSetIlluminant.Text = 'Change illuminant';
            app.menuEditSetIlluminant.Tag = 'menuEditSetIlluminant';
            app.menuResampleWave = uimenu(app.menuSpectral);
            app.menuResampleWave.MenuSelectedFcn = createCallbackFcn(app, @menuResampleWave_Callback, true);
            app.menuResampleWave.Separator = 'on';
            app.menuResampleWave.Text = 'Resample wavelength';
            app.menuResampleWave.Tag = 'menuResampleWave';
            app.menuEditAdjustMonochrome = uimenu(app.menuSpectral);
            app.menuEditAdjustMonochrome.MenuSelectedFcn = createCallbackFcn(app, @menuEditAdjustMonochrome_Callback, true);
            app.menuEditAdjustMonochrome.Text = 'Adjust monochrome wavelength';
            app.menuEditAdjustMonochrome.Tag = 'menuEditAdjustMonochrome';
            app.menuEditTransform = uimenu(app.menuEdit);
            app.menuEditTransform.MenuSelectedFcn = createCallbackFcn(app, @menuEditTransform_Callback, true);
            app.menuEditTransform.Separator = 'on';
            app.menuEditTransform.Text = 'Adjust space';
            app.menuEditTransform.Tag = 'menuEditTransform';
            app.menuEditScaleSize = uimenu(app.menuEditTransform);
            app.menuEditScaleSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditScaleSize_Callback, true);
            app.menuEditScaleSize.Text = 'Scale size';
            app.menuEditScaleSize.Tag = 'menuEditScaleSize';
            app.editCrop = uimenu(app.menuEditTransform);
            app.editCrop.MenuSelectedFcn = createCallbackFcn(app, @editCrop_Callback, true);
            app.editCrop.Text = 'Crop';
            app.editCrop.Tag = 'editCrop';
            app.menuEditTranspose = uimenu(app.menuEditTransform);
            app.menuEditTranspose.MenuSelectedFcn = createCallbackFcn(app, @menuEditTranspose_Callback, true);
            app.menuEditTranspose.Text = 'Transpose';
            app.menuEditTranspose.Tag = 'menuEditTranspose';
            app.menuEditRotate = uimenu(app.menuEditTransform);
            app.menuEditRotate.MenuSelectedFcn = createCallbackFcn(app, @menuEditRotate_Callback, true);
            app.menuEditRotate.Text = 'Rotate';
            app.menuEditRotate.Tag = 'menuEditRotate';
            app.menuEditRotCW = uimenu(app.menuEditRotate);
            app.menuEditRotCW.MenuSelectedFcn = createCallbackFcn(app, @menuEditRotCW_Callback, true);
            app.menuEditRotCW.Text = 'Clockwise';
            app.menuEditRotCW.Tag = 'menuEditRotCW';
            app.menuEditRotCCW = uimenu(app.menuEditRotate);
            app.menuEditRotCCW.MenuSelectedFcn = createCallbackFcn(app, @menuEditRotCCW_Callback, true);
            app.menuEditRotCCW.Text = 'Counterclockwise';
            app.menuEditRotCCW.Tag = 'menuEditRotCCW';
            app.menuEditFlip = uimenu(app.menuEditTransform);
            app.menuEditFlip.MenuSelectedFcn = createCallbackFcn(app, @menuEditFlip_Callback, true);
            app.menuEditFlip.Text = 'Flip';
            app.menuEditFlip.Tag = 'menuEditFlip';
            app.menuEditFlipHorizontal = uimenu(app.menuEditFlip);
            app.menuEditFlipHorizontal.MenuSelectedFcn = createCallbackFcn(app, @menuEditFlipHorizontal_Callback, true);
            app.menuEditFlipHorizontal.Text = 'Horizontal';
            app.menuEditFlipHorizontal.Tag = 'menuEditFlipHorizontal';
            app.menuEditFlipVertical = uimenu(app.menuEditFlip);
            app.menuEditFlipVertical.MenuSelectedFcn = createCallbackFcn(app, @menuEditFlipVertical_Callback, true);
            app.menuEditFlipVertical.Text = 'Vertical';
            app.menuEditFlipVertical.Tag = 'menuEditFlipVertical';
            app.DenoiseMenu = uimenu(app.menuEdit);
            app.DenoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @menuEditDenoise_Callback, true);
            app.DenoiseMenu.Text = 'Denoise';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Separator = 'on';
            app.menuEditFontSize.Text = 'Font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuEditClearWindow = uimenu(app.menuEdit);
            app.menuEditClearWindow.MenuSelectedFcn = createCallbackFcn(app, @menuEditClearWindow_Callback, true);
            app.menuEditClearWindow.Text = 'Clear window message';
            app.menuEditClearWindow.Tag = 'menuEditClearWindow';
            app.menuEditViewer = uimenu(app.menuEdit);
            app.menuEditViewer.MenuSelectedFcn = createCallbackFcn(app, @menuEditViewer_Callback, true);
            app.menuEditViewer.Text = 'Viewer';
            app.menuEditViewer.Tag = 'menuEditViewer';
            app.PlotMenu = uimenu(app.figure1);
            app.PlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotMenu_Callback, true);
            app.PlotMenu.Text = 'Plot';
            app.PlotMenu.Tag = 'PlotMenu';
            app.menuPlotRadiance = uimenu(app.PlotMenu);
            app.menuPlotRadiance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotRadiance_Callback, true);
            app.menuPlotRadiance.Text = 'Radiance (photons)';
            app.menuPlotRadiance.Tag = 'plotRadiance';
            app.menuPlotRadianceE = uimenu(app.PlotMenu);
            app.menuPlotRadianceE.MenuSelectedFcn = createCallbackFcn(app, @menuPlotRadianceE_Callback, true);
            app.menuPlotRadianceE.Text = 'Radiance (energy)';
            app.menuPlotRadianceE.Tag = 'menuPlotRadianceE';
            app.menuPlotRadImGrid = uimenu(app.PlotMenu);
            app.menuPlotRadImGrid.MenuSelectedFcn = createCallbackFcn(app, @menuPlotRadImGrid_Callback, true);
            app.menuPlotRadImGrid.Text = 'Radiance  image (grid)';
            app.menuPlotRadImGrid.Tag = 'menuPlotRadImGrid';
            app.menuPlotIllumPhotons = uimenu(app.PlotMenu);
            app.menuPlotIllumPhotons.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIllumPhotons_Callback, true);
            app.menuPlotIllumPhotons.Separator = 'on';
            app.menuPlotIllumPhotons.Text = 'Illuminant (photons)';
            app.menuPlotIllumPhotons.Tag = 'menuPlotIllumPhotons';
            app.menuPlotIlluminant = uimenu(app.PlotMenu);
            app.menuPlotIlluminant.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIlluminant_Callback, true);
            app.menuPlotIlluminant.Text = 'Illuminant (energy)';
            app.menuPlotIlluminant.Tag = 'menuPlotIlluminant';
            app.menuPlotIlluminantImage = uimenu(app.PlotMenu);
            app.menuPlotIlluminantImage.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIlluminantImage_Callback, true);
            app.menuPlotIlluminantImage.Text = 'Illuminant image';
            app.menuPlotIlluminantImage.Tag = 'menuPlotIlluminantImage';
            app.menuPlotReflectance = uimenu(app.PlotMenu);
            app.menuPlotReflectance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotReflectance_Callback, true);
            app.menuPlotReflectance.Separator = 'on';
            app.menuPlotReflectance.Text = 'Reflectance';
            app.menuPlotReflectance.Tag = 'menuPlotReflectance';
            app.menuPlotImTrueSize = uimenu(app.PlotMenu);
            app.menuPlotImTrueSize.MenuSelectedFcn = createCallbackFcn(app, @menuPlotImTrueSize_Callback, true);
            app.menuPlotImTrueSize.Separator = 'on';
            app.menuPlotImTrueSize.Text = 'Image (RGB)';
            app.menuPlotImTrueSize.Tag = 'menuPlotImTrueSize';
            app.menuPlotMultipleRGB = uimenu(app.PlotMenu);
            app.menuPlotMultipleRGB.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMultipleRGB_Callback, true);
            app.menuPlotMultipleRGB.Text = 'Image multiple (RGB)';
            app.menuPlotMultipleRGB.Tag = 'menuPlotMultipleRGB';
            app.SingleWindowMenu = uimenu(app.menuPlotMultipleRGB);
            app.SingleWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @rgbSingleWindow, true);
            app.SingleWindowMenu.Text = 'Single Window';
            app.MultipleWindowsMenu = uimenu(app.menuPlotMultipleRGB);
            app.MultipleWindowsMenu.MenuSelectedFcn = createCallbackFcn(app, @rgbMultipleWindows, true);
            app.MultipleWindowsMenu.Text = 'Multiple Windows';
            app.menuPlotImageSpectral = uimenu(app.PlotMenu);
            app.menuPlotImageSpectral.MenuSelectedFcn = createCallbackFcn(app, @plotImageSpectral, true);
            app.menuPlotImageSpectral.Text = 'Image spectral';
            app.menuPlotDepth = uimenu(app.PlotMenu);
            app.menuPlotDepth.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDepth_Callback, true);
            app.menuPlotDepth.Separator = 'on';
            app.menuPlotDepth.Text = 'Depth map';
            app.menuPlotDepth.Tag = 'menuPlotDepth';
            app.menuPlotDepthContour = uimenu(app.PlotMenu);
            app.menuPlotDepthContour.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDepthContour_Callback, true);
            app.menuPlotDepthContour.Text = 'Depth contour';
            app.menuPlotDepthContour.Tag = 'menuPlotDepthContour';
            app.menuScene = uimenu(app.figure1);
            app.menuScene.MenuSelectedFcn = createCallbackFcn(app, @menuScene_Callback, true);
            app.menuScene.Text = 'Scene';
            app.menuScene.Tag = 'menuScene';
            app.menuSceneMacbeth = uimenu(app.menuScene);
            app.menuSceneMacbeth.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbeth_Callback, true);
            app.menuSceneMacbeth.Text = 'Macbeth charts';
            app.menuSceneMacbeth.Tag = 'menuSceneMacbeth';
            app.menuSceneMacbethTungsten = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethTungsten.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethTungsten_Callback, true);
            app.menuSceneMacbethTungsten.Text = 'Tungsten';
            app.menuSceneMacbethTungsten.Tag = 'menuSceneMacbethTungsten';
            app.menuSceneMacbethD50 = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethD50.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethD50_Callback, true);
            app.menuSceneMacbethD50.Text = 'D 50';
            app.menuSceneMacbethD50.Tag = 'menuSceneMacbethD50';
            app.menuSceneMacbethD65 = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethD65.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethD65_Callback, true);
            app.menuSceneMacbethD65.Text = 'D 65';
            app.menuSceneMacbethD65.Tag = 'menuSceneMacbethD65';
            app.menuSceneMacbethC = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethC.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethC_Callback, true);
            app.menuSceneMacbethC.Text = 'Illuminant C';
            app.menuSceneMacbethC.Tag = 'menuSceneMacbethC';
            app.menuSceneMacbethFluorescent = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethFluorescent.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethFluorescent_Callback, true);
            app.menuSceneMacbethFluorescent.Text = 'Fluorescent';
            app.menuSceneMacbethFluorescent.Tag = 'menuSceneMacbethFluorescent';
            app.menuSceneMacbethVisIR = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethVisIR.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethVisIR_Callback, true);
            app.menuSceneMacbethVisIR.Text = 'Visible-Infrared';
            app.menuSceneMacbethVisIR.Tag = 'menuSceneMacbethVisIR';
            app.menuSceneLstar = uimenu(app.menuSceneMacbeth);
            app.menuSceneLstar.MenuSelectedFcn = createCallbackFcn(app, @menuSceneLstar_Callback, true);
            app.menuSceneLstar.Separator = 'on';
            app.menuSceneLstar.Text = 'Lstar chart';
            app.menuSceneLstar.Tag = 'menuSceneLstar';
            app.menuScenesTest = uimenu(app.menuScene);
            app.menuScenesTest.MenuSelectedFcn = createCallbackFcn(app, @menuScenesTest_Callback, true);
            app.menuScenesTest.Text = 'Patterns';
            app.menuScenesTest.Tag = 'menuScenesTest';
            app.menuScenesSweep = uimenu(app.menuScenesTest);
            app.menuScenesSweep.MenuSelectedFcn = createCallbackFcn(app, @menuScenesSweep_Callback, true);
            app.menuScenesSweep.Text = 'Sweep frequency';
            app.menuScenesSweep.Tag = 'menuScenesSweep';
            app.menuHarmonic = uimenu(app.menuScenesTest);
            app.menuHarmonic.MenuSelectedFcn = createCallbackFcn(app, @menuHarmonic_Callback, true);
            app.menuHarmonic.Text = 'Harmonic';
            app.menuHarmonic.Tag = 'menuHarmonic';
            app.menuSceneFreqOrient = uimenu(app.menuScenesTest);
            app.menuSceneFreqOrient.MenuSelectedFcn = createCallbackFcn(app, @menuSceneFreqOrient_Callback, true);
            app.menuSceneFreqOrient.Text = 'Freq-orientation';
            app.menuSceneFreqOrient.Tag = 'menuSceneFreqOrient';
            app.menuSceneSlantedBar = uimenu(app.menuScenesTest);
            app.menuSceneSlantedBar.MenuSelectedFcn = createCallbackFcn(app, @menuSceneSlantedBar_Callback, true);
            app.menuSceneSlantedBar.Separator = 'on';
            app.menuSceneSlantedBar.Text = 'Slanted bar (ISO 12233, ephot)';
            app.menuSceneSlantedBar.Tag = 'menuSceneSlantedBar';
            app.menuSceneZonePlate = uimenu(app.menuScenesTest);
            app.menuSceneZonePlate.MenuSelectedFcn = createCallbackFcn(app, @menuSceneZonePlate_Callback, true);
            app.menuSceneZonePlate.Text = 'Zone plate (Circular, ephot)';
            app.menuSceneZonePlate.Tag = 'menuSceneZonePlate';
            app.menuSceneMackay = uimenu(app.menuScenesTest);
            app.menuSceneMackay.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMackay_Callback, true);
            app.menuSceneMackay.Text = 'Mackay (rings/rays, ephot)';
            app.menuSceneMackay.Tag = 'menuSceneMackay';
            app.menuSceneCheckerboard = uimenu(app.menuScenesTest);
            app.menuSceneCheckerboard.MenuSelectedFcn = createCallbackFcn(app, @menuSceneCheckerboard_Callback, true);
            app.menuSceneCheckerboard.Text = 'Checkerboard (ephot)';
            app.menuSceneCheckerboard.Tag = 'menuSceneCheckerboard';
            app.menuScenePointArray = uimenu(app.menuScenesTest);
            app.menuScenePointArray.MenuSelectedFcn = createCallbackFcn(app, @menuScenePointArray_Callback, true);
            app.menuScenePointArray.Separator = 'on';
            app.menuScenePointArray.Text = 'Point array (D65)';
            app.menuScenePointArray.Tag = 'menuScenePointArray';
            app.menuSceneGridLines = uimenu(app.menuScenesTest);
            app.menuSceneGridLines.MenuSelectedFcn = createCallbackFcn(app, @menuSceneGridLines_Callback, true);
            app.menuSceneGridLines.Text = 'Grid lines (D65)';
            app.menuSceneGridLines.Tag = 'menuSceneGridLines';
            app.menuSceneTestLine = uimenu(app.menuScenesTest);
            app.menuSceneTestLine.MenuSelectedFcn = createCallbackFcn(app, @menuSceneTestLine_Callback, true);
            app.menuSceneTestLine.Text = 'Line (D65,vert)';
            app.menuSceneTestLine.Tag = 'menuSceneTestLine';
            app.menuSceneRadialLines = uimenu(app.menuScenesTest);
            app.menuSceneRadialLines.MenuSelectedFcn = createCallbackFcn(app, @menuSceneRadialLines_Callback, true);
            app.menuSceneRadialLines.Text = 'Radial lines';
            app.menuSceneRadialLines.Tag = 'menuSceneRadialLines';
            app.menuUniform = uimenu(app.menuScene);
            app.menuUniform.MenuSelectedFcn = createCallbackFcn(app, @menuUniform_Callback, true);
            app.menuUniform.Text = 'Uniform';
            app.menuUniform.Tag = 'menuUniform';
            app.menuSceneUniformPhoton = uimenu(app.menuUniform);
            app.menuSceneUniformPhoton.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformPhoton_Callback, true);
            app.menuSceneUniformPhoton.Text = 'Equal photon (400-700)';
            app.menuSceneUniformPhoton.Tag = 'menuSceneUniformPhoton';
            app.menuSceneUniformEE = uimenu(app.menuUniform);
            app.menuSceneUniformEE.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformEE_Callback, true);
            app.menuSceneUniformEE.Text = 'Equal energy (400-700)';
            app.menuSceneUniformEE.Tag = 'menuSceneUniformEE';
            app.menuUniformD65 = uimenu(app.menuUniform);
            app.menuUniformD65.MenuSelectedFcn = createCallbackFcn(app, @menuUniformD65_Callback, true);
            app.menuUniformD65.Text = 'D65 (400-700)';
            app.menuUniformD65.Tag = 'menuUniformD65';
            app.menuSceneUniformEESpecify = uimenu(app.menuUniform);
            app.menuSceneUniformEESpecify.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformEESpecify_Callback, true);
            app.menuSceneUniformEESpecify.Separator = 'on';
            app.menuSceneUniformEESpecify.Text = 'Equal energy (specify wave)';
            app.menuSceneUniformEESpecify.Tag = 'menuSceneUniformEESpecify';
            app.menuSceneUniformBBspecify = uimenu(app.menuUniform);
            app.menuSceneUniformBBspecify.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformBBspecify_Callback, true);
            app.menuSceneUniformBBspecify.Text = 'Blackbody (specify)';
            app.menuSceneUniformBBspecify.Tag = 'menuSceneUniformBBspecify';
            app.menuScenesRamp = uimenu(app.menuUniform);
            app.menuScenesRamp.MenuSelectedFcn = createCallbackFcn(app, @menuScenesRamp_Callback, true);
            app.menuScenesRamp.Separator = 'on';
            app.menuScenesRamp.Text = 'Ramps';
            app.menuScenesRamp.Tag = 'menuScenesRamp';
            app.menuSceneNoise = uimenu(app.menuUniform);
            app.menuSceneNoise.MenuSelectedFcn = createCallbackFcn(app, @menuSceneNoise_Callback, true);
            app.menuSceneNoise.Separator = 'on';
            app.menuSceneNoise.Text = 'Random noise';
            app.menuSceneNoise.Tag = 'menuSceneNoise';
            app.menuSceneFile = uimenu(app.menuScene);
            app.menuSceneFile.MenuSelectedFcn = createCallbackFcn(app, @menuSceneFile_Callback, true);
            app.menuSceneFile.Text = 'Choose File';
            app.menuSceneFile.Tag = 'menuSceneFile';
            app.menuSceneMultiSpec = uimenu(app.menuSceneFile);
            app.menuSceneMultiSpec.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMultiSpec_Callback, true);
            app.menuSceneMultiSpec.Text = 'Multispectral';
            app.menuSceneMultiSpec.Tag = 'menuSceneMultiSpec';
            app.menuSceneChooseRGB = uimenu(app.menuSceneFile);
            app.menuSceneChooseRGB.MenuSelectedFcn = createCallbackFcn(app, @menuSceneChooseRGB_Callback, true);
            app.menuSceneChooseRGB.Text = 'RGB';
            app.menuSceneChooseRGB.Tag = 'menuSceneChooseRGB';
            app.menuFileChooseFileMono = uimenu(app.menuSceneFile);
            app.menuFileChooseFileMono.MenuSelectedFcn = createCallbackFcn(app, @menuFileChooseFileMono_Callback, true);
            app.menuFileChooseFileMono.Text = 'Monochrome';
            app.menuFileChooseFileMono.Tag = 'menuFileChooseFileMono';
            app.menuAn = uimenu(app.figure1);
            app.menuAn.MenuSelectedFcn = createCallbackFcn(app, @menuAn_Callback, true);
            app.menuAn.Text = 'Analyze';
            app.menuAn.Tag = 'menuAn';
            app.menuAnalyzeLine = uimenu(app.menuAn);
            app.menuAnalyzeLine.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLine_Callback, true);
            app.menuAnalyzeLine.Text = 'Line (luminance)';
            app.menuAnalyzeLine.Tag = 'menuAnalyzeLine';
            app.menuAnalyzeLineH = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLineH.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLineH_Callback, true);
            app.menuAnalyzeLineH.Text = 'Horizontal';
            app.menuAnalyzeLineH.Tag = 'menuAnalyzeLineH';
            app.menuAnalyzeLineV = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLineV.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLineV_Callback, true);
            app.menuAnalyzeLineV.Text = 'Vertical';
            app.menuAnalyzeLineV.Tag = 'menuAnalyzeLineV';
            app.menuAnalyzeLFFTH = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLFFTH.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLFFTH_Callback, true);
            app.menuAnalyzeLFFTH.Text = 'FFT Horizontal';
            app.menuAnalyzeLFFTH.Tag = 'menuAnalyzeLFFTH';
            app.menuAnalyzeLFFTv = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLFFTv.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLFFTv_Callback, true);
            app.menuAnalyzeLFFTv.Text = 'FFT Vertical';
            app.menuAnalyzeLFFTv.Tag = 'menuAnalyzeLFFTv';
            app.menuAnalyzeLineWave = uimenu(app.menuAn);
            app.menuAnalyzeLineWave.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLineWave_Callback, true);
            app.menuAnalyzeLineWave.Text = 'Line x wave';
            app.menuAnalyzeLineWave.Tag = 'menuAnalyzeLineWave';
            app.menuAnalyzeLWH = uimenu(app.menuAnalyzeLineWave);
            app.menuAnalyzeLWH.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLWH_Callback, true);
            app.menuAnalyzeLWH.Text = 'Horizontal';
            app.menuAnalyzeLWH.Tag = 'menuAnalyzeLWH';
            app.menuAnalyzeLWV = uimenu(app.menuAnalyzeLineWave);
            app.menuAnalyzeLWV.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLWV_Callback, true);
            app.menuAnalyzeLWV.Text = 'Vertical';
            app.menuAnalyzeLWV.Tag = 'menuAnalyzeLWV';
            app.menuAnalyzeROI = uimenu(app.menuAn);
            app.menuAnalyzeROI.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeROI_Callback, true);
            app.menuAnalyzeROI.Separator = 'on';
            app.menuAnalyzeROI.Text = 'ROI Summary';
            app.menuAnalyzeROI.Tag = 'menuAnalyzeROI';
            app.menuLuminance = uimenu(app.menuAnalyzeROI);
            app.menuLuminance.MenuSelectedFcn = createCallbackFcn(app, @menuLuminance_Callback, true);
            app.menuLuminance.Text = 'Luminance (Y)';
            app.menuLuminance.Tag = 'menuLuminance';
            app.menuAnalyzeChromaticity = uimenu(app.menuAnalyzeROI);
            app.menuAnalyzeChromaticity.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeChromaticity_Callback, true);
            app.menuAnalyzeChromaticity.Text = 'Chromaticity (xy)';
            app.menuAnalyzeChromaticity.Tag = 'menuAnalyzeChromaticity';
            app.menPlotLumLin = uimenu(app.menuAn);
            app.menPlotLumLin.MenuSelectedFcn = createCallbackFcn(app, @menPlotLumLin_Callback, true);
            app.menPlotLumLin.Separator = 'on';
            app.menPlotLumLin.Text = 'Mesh (luminance, linear)';
            app.menPlotLumLin.Tag = 'menPlotLumLin';
            app.PlotLuminance = uimenu(app.menuAn);
            app.PlotLuminance.MenuSelectedFcn = createCallbackFcn(app, @PlotLuminance_Callback, true);
            app.PlotLuminance.Text = 'Mesh (luminance, log10)';
            app.PlotLuminance.Tag = 'PlotLuminance';
            app.menuAnIlluminantCCT = uimenu(app.menuAn);
            app.menuAnIlluminantCCT.MenuSelectedFcn = createCallbackFcn(app, @menuAnIlluminantCCT_Callback, true);
            app.menuAnIlluminantCCT.Separator = 'on';
            app.menuAnIlluminantCCT.Text = 'Illuminant CCT';
            app.menuAnIlluminantCCT.Tag = 'menuAnIlluminantCCT';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpSceneProgrammers = uimenu(app.menuHelp);
            app.menuHelpSceneProgrammers.MenuSelectedFcn = createCallbackFcn(app, @menuHelpSceneProgrammers_Callback, true);
            app.menuHelpSceneProgrammers.Text = 'Scene functions';
            app.menuHelpSceneProgrammers.Tag = 'menuHelpSceneProgrammers';
            app.menuHelpProgGuide = uimenu(app.menuHelp);
            app.menuHelpProgGuide.MenuSelectedFcn = createCallbackFcn(app, @menuHelpProgGuide_Callback, true);
            app.menuHelpProgGuide.Text = 'ISET functions';
            app.menuHelpProgGuide.Tag = 'menuHelpProgGuide';
            app.menuHelpAppNotes = uimenu(app.menuHelp);
            app.menuHelpAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuHelpAppNotes_Callback, true);
            app.menuHelpAppNotes.Text = 'Documentation (web)';
            app.menuHelpAppNotes.Tag = 'menuHelpAppNotes';
            app.boxScene = uibuttongroup(app.figure1);
            app.boxScene.Tag = 'boxScene';
            app.editLuminance = uieditfield(app.boxScene, 'text');
            app.editLuminance.ValueChangedFcn = createCallbackFcn(app, @editLuminance_Callback, true);
            app.editLuminance.Tag = 'editLuminance';
            app.editLuminance.Tooltip = 'Mean image luminance';
            app.txtLum = uilabel(app.boxScene);
            app.txtLum.Tag = 'txtLum';
            app.txtLum.Tooltip = 'Mean luminance';
            app.txtLum.Text = 'Luminance';
            app.txtCDM2 = uilabel(app.boxScene);
            app.txtCDM2.Tag = 'txtCDM2';
            app.txtCDM2.Tooltip = 'Candelas per meter squared';
            app.txtCDM2.Text = 'cd/m2';
            app.editHorFOV = uieditfield(app.boxScene, 'text');
            app.editHorFOV.ValueChangedFcn = createCallbackFcn(app, @editHorFOV_Callback, true);
            app.editHorFOV.Tag = 'editHorFOV';
            app.editHorFOV.Tooltip = 'Horizontal field of view';
            app.txtFOV = uilabel(app.boxScene);
            app.txtFOV.Tag = 'txtFOV';
            app.txtFOV.Tooltip = 'Field of View (horizontal)';
            app.txtFOV.Text = 'FOV (width)';
            app.txtDeg = uilabel(app.boxScene);
            app.txtDeg.Tag = 'txtDeg';
            app.txtDeg.Tooltip = 'Degrees of visual angle';
            app.txtDeg.Text = 'deg';
            app.editDistance = uieditfield(app.boxScene, 'text');
            app.editDistance.ValueChangedFcn = createCallbackFcn(app, @editDistance_Callback, true);
            app.editDistance.Tag = 'editDistance';
            app.editDistance.Tooltip = 'Distance to image plane';
            app.txtDist = uilabel(app.boxScene);
            app.txtDist.Tag = 'txtDist';
            app.txtDist.Tooltip = 'Distance to image plane';
            app.txtDist.Text = 'Distance';
            app.txtM = uilabel(app.boxScene);
            app.txtM.Tag = 'txtM';
            app.txtM.Tooltip = 'Meters';
            app.txtM.Text = 'm';
            app.boxDescription = uibuttongroup(app.figure1);
            app.boxDescription.Tag = 'boxDescription';
            app.txtSceneDescription = uilabel(app.boxDescription);
            app.txtSceneDescription.Tag = 'txtSceneDescription';
            app.txtSceneDescription.Tooltip = 'Scene description';
            app.txtSceneDescription.Text = 'Scene Description';
            app.MoreinfoButton = uibutton(app.figure1, 'push');
            app.MoreinfoButton.ButtonPushedFcn = createCallbackFcn(app, @MoreinfoButtonPushed, true);
            app.MoreinfoButton.Enable = 'off';
            app.MoreinfoButton.Visible = 'off';
            app.MoreinfoButton.Text = 'More info...';
            app.Panel = uipanel(app.figure1);
            app.sceneImage = uiaxes(app.Panel);
            app.sceneImage.XLim = [0 1];
            app.sceneImage.YLim = [0 1];
            app.sceneImage.ZLim = [0 1];
            app.sceneImage.CLim = [0 1];
            app.sceneImage.ALim = [0 1];
            app.sceneImage.XTick = [];
            app.sceneImage.XTickLabel = '';
            app.sceneImage.YTick = zeros(1,0);
            app.sceneImage.YTickLabel = '';
            app.sceneImage.ZTick = [0 0.5 1];
            app.sceneImage.ZTickLabel = '';
            app.sceneImage.Tag = 'sceneImage';
            app.popupSelectScene = uidropdown(app.Panel);
            app.popupSelectScene.Items = {'New Scene'};
            app.popupSelectScene.ValueChangedFcn = createCallbackFcn(app, @popupSelectScene_Callback, true);
            app.popupSelectScene.Tag = 'popupSelectScene';
            app.popupSelectScene.Tooltip = 'Select scene';
            app.popupSelectScene.Value = 'New Scene';
            app.btnNext = uibutton(app.Panel, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNext_Callback, true);
            app.btnNext.Tag = 'btnNext';
            app.btnNext.Icon = 'rightArrow.png';
            app.btnNext.Text = '';
            app.btnPrev = uibutton(app.Panel, 'push');
            app.btnPrev.ButtonPushedFcn = createCallbackFcn(app, @btnPrev_Callback, true);
            app.btnPrev.Tag = 'btnPrev';
            app.btnPrev.Icon = 'leftArrow.png';
            app.btnPrev.Text = '';
            app.Panel_2 = uipanel(app.Panel);
            app.editGamma = uieditfield(app.Panel_2, 'text');
            app.editGamma.ValueChangedFcn = createCallbackFcn(app, @editGamma_Callback, true);
            app.editGamma.Tag = 'editGamma';
            app.editGamma.Value = '1';
            app.txtDisplayGamma = uilabel(app.Panel_2);
            app.txtDisplayGamma.Tag = 'txtDisplayGamma';
            app.txtDisplayGamma.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.txtDisplayGamma.Text = 'Gamma';
            app.text21 = uilabel(app.Panel_2);
            app.text21.Tag = 'text21';
            app.text21.Tooltip = 'Set display rendering, no data effect';
            app.text21.Text = 'Render';
            app.popupRender = uidropdown(app.Panel_2);
            app.popupRender.Items = {'Standard RGB', 'Gray Scale', 'HDR', 'Clip highlights'};
            app.popupRender.ValueChangedFcn = createCallbackFcn(app, @popupRender_Callback, true);
            app.popupRender.Tag = 'popupDisplay';
            app.popupRender.Value = 'Standard RGB';
            app.txtMessage = uilabel(app.Panel);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.UITable = uitable(app.figure1);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.imageMenu = uicontextmenu(app.figure1);
            app.HorLumLineMenu = uimenu(app.imageMenu);
            app.HorLumLineMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLineH_Callback, true);
            app.HorLumLineMenu.Text = 'Hor Lum Line';
            app.RefreshMenu = uimenu(app.imageMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.RefreshMenu.Text = 'Refresh';
            % Assign app.imageMenu
            app.sceneImage.ContextMenu = app.imageMenu;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sceneWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)sceneWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end