classdef webImageBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GreenAxes                    matlab.ui.control.UIAxes
        BlueAxes                     matlab.ui.control.UIAxes
        PredefinedImagesLabel        matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        LoadButton                   matlab.ui.control.Button
        RedAxes                      matlab.ui.control.UIAxes
        ISETSampleImageFinderLabel   matlab.ui.control.Label
        EnterkeywordsEditFieldLabel  matlab.ui.control.Label
        EnterkeywordsEditField       matlab.ui.control.EditField
        SelectSourceDropDownLabel    matlab.ui.control.Label
        SelectSourceDropDown         matlab.ui.control.DropDown
        FirstReturnedImageLabel      matlab.ui.control.Label
        OpeninISETButton             matlab.ui.control.Button
        OpeninBrowserButton          matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        ViewFolderButton             matlab.ui.control.Button
        DummyUIAxes                  matlab.ui.control.UIAxes
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        ImageAxesArray matlab.ui.control.UIAxes
        btn matlab.ui.controls.ToolbarPushButton
        tb matlab.ui.controls.AxesToolbar
        fname
        imgGridColumns
        imgGridRows
    end
    
    methods (Access = private)
        function ImageButtonCallback(app, btn)
            uialert(app.UIFigure,'Got a callback',"now what?")
        end

        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
                app.fname = imagefile;
                app.FirstReturnedImageLabel.Text = imagefile;
            else
                try
                    im = imread(imagefile);
                    app.fname = imagefile;
                    app.FirstReturnedImageLabel.Text = imagefile;
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            ourImageControl = app.ImageAxesArray(1); % ExpERIMENT djc
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(ourImageControl,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    %imagesc(app.ImageAxes,im);
                    imagesc(ourImageControl,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Update the image and histograms
            % djc can't do this live if we haven't created the grid yet!S
            %updateimage(app, 'peppers.png');
            
            app.imgGridRows = 2;
            app.imgGridColumns = 3;
            gridSize = app.Panel.InnerPosition;
            gridWidth = gridSize(3);
            gridHeight = gridSize(4);
            imageSpacer = 0; % In Case
            imageWidth = floor(gridWidth / app.imgGridColumns) - imageSpacer;
            imageHeight = floor(gridHeight / app.imgGridRows) - imageSpacer;

            for i = 1:app.imgGridColumns
                for j = 1:app.imgGridRows
                    app.ImageAxesArray(i,j) = uiaxes(app.Panel);
                    app.ImageAxesArray(i,j).Box = 'on';
                    app.ImageAxesArray(i,j).XTick = [];
                    app.ImageAxesArray(i,j).XTickLabel = {'[ ]'};
                    app.ImageAxesArray(i,j).YTick = [];
                    app.ImageAxesArray(i,j).Position = [(i-1)*imageWidth (j-1)*imageHeight imageWidth imageHeight];
                    app.tb(i,j) = axtoolbar(app.ImageAxesArray(i,j), {'export', 'restoreview'});
                    app.btn(i,j) = axtoolbarbtn(app.tb(i,j), 'push');
                    app.btn(i,j).Tooltip = 'PUSH ME!';
                    app.btn(i,j).ButtonPushedFcn = {@ImageButtonCallback,app, app.btn(i,j)};
                end
            end

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function: Image, LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               app.fname = [p f];
               updateimage(app, app.fname);
            end
        end

        % Button pushed function: OpeninISETButton
        function OpeninISETButtonPushed(app, event)
            scene = sceneFromFile(app.fname,'rgb');
            ieAddObject(scene); sceneWindow;
        end

        % Button pushed function: OpeninBrowserButton
        function OpeninBrowserButtonPushed(app, event)
            % djc hack to get a random folder
            if isdeployed
                uialert(app.UIFigure, 'Image Browser not supported in runtime. Sorry.', 'Image Browser not Available');
            else
                [fPath, ~, ~] = fileparts(which(app.fname));
                imageBrowser(fPath);            
            end

        end

        % Button pushed function: ViewFolderButton
        function ViewFolderButtonPushed(app, event)
            % for now just get the current folder for grins
            % only works when you actually manually load an image from a
            % folder somewhere
            [fPath, ~, ~] = fileparts(app.fname);
            files = dir(fullfile(fPath,'*.jpg'));
            for i = 1:app.imgGridRows
                for j = 1:app.imgGridColumns
                    if i*j <= length(files)
                        ourImageFile = fullfile(files(i*j).folder,files(i*j).name);
                        ourImage = imread(ourImageFile);
                        imagesc(app.ImageAxesArray(i*j),ourImage);
                    end
                end
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            uialert(app.UIFigure,'Figure Button down called.', "Okay?")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ISET Image Finder';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Peppers', 'Corn'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.ISETSampleImageFinderLabel = uilabel(app.UIFigure);
            app.ISETSampleImageFinderLabel.Text = 'ISET Sample Image Finder';
            app.EnterkeywordsEditFieldLabel = uilabel(app.UIFigure);
            app.EnterkeywordsEditFieldLabel.Text = 'Enter keywords';
            app.EnterkeywordsEditField = uieditfield(app.UIFigure, 'text');
            app.SelectSourceDropDownLabel = uilabel(app.UIFigure);
            app.SelectSourceDropDownLabel.Text = 'Select Source';
            app.SelectSourceDropDown = uidropdown(app.UIFigure);
            app.SelectSourceDropDown.Items = {'Unsplash'};
            app.SelectSourceDropDown.Value = 'Unsplash';
            app.FirstReturnedImageLabel = uilabel(app.UIFigure);
            app.FirstReturnedImageLabel.Text = 'First Returned Image';
            app.OpeninISETButton = uibutton(app.UIFigure, 'push');
            app.OpeninISETButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninISETButtonPushed, true);
            app.OpeninISETButton.Text = 'Open in ISET';
            app.OpeninBrowserButton = uibutton(app.UIFigure, 'push');
            app.OpeninBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninBrowserButtonPushed, true);
            app.OpeninBrowserButton.Text = 'Open in Browser';
            app.Panel = uipanel(app.UIFigure);
            app.ViewFolderButton = uibutton(app.UIFigure, 'push');
            app.ViewFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFolderButtonPushed, true);
            app.ViewFolderButton.Text = 'View Folder';
            app.DummyUIAxes = uiaxes(app.UIFigure);
            title(app.DummyUIAxes, 'Title')
            xlabel(app.DummyUIAxes, 'X')
            ylabel(app.DummyUIAxes, 'Y')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webImageBrowser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end